classdef ControlGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LeftPanel                   matlab.ui.container.Panel
        CurrentStateEditField       matlab.ui.control.EditField
        CurrentStateEditFieldLabel  matlab.ui.control.Label
        COMPortEditField            matlab.ui.control.EditField
        COMPortEditFieldLabel       matlab.ui.control.Label
        LiveVideoOnoffCheckBox      matlab.ui.control.CheckBox
        ArduinoOnOffCheckBox        matlab.ui.control.CheckBox
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        arduino1;
        arduinoOn = false;
        framerate = 0.03;
        vid;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Key press function: UIFigure
        function directionsPressed(app, event)
            % sends a signal to the arduino depending on which key is
            % pressed
            key = event.Key;
            switch key
            case 'w'
                dir = 'N';
            case 's'
                dir = 'S';
            case 'a'
                dir = 'W';
            case 'd'
                dir = 'E';
            case 'q'
                dir = 'A';
            case 'e'
                dir = 'B';
            case 'z'
                dir = 'D';
            case 'c'
                dir = 'C';
            case 'f'
                dir = 'F';
            otherwise
                dir = 'X';
            end
            if app.arduinoOn
                write(app.arduino1, dir, 'char');
                flush(app.arduino1);
            end

            % updates the Current State textbox with the sent direction
            app.CurrentStateEditField.Value = dir;
            pause(app.framerate);

            % closes the connection once the user hits the key that determines 'F'
            if dir == 'F'
                pause(1);
                if app.arduinoOn
                % Outputs the "target reached" response from the Arduino
                    data = readline(app.arduino1); 
                    app.CurrentStateEditField.Value = data;
                    pause(.5);
                
                    % Clean up
                    clear app.arduino1;
                    delete(app.arduino1);
                    app.ArduinoOnOffCheckBox.Value = false;
                    app.arduinoOn = false;
                end
            end
        end

        % Key release function: UIFigure
        function directionsReleased(app, event)
            % When a key is released, ensures no movements are sent to the
            % magnet
            key = event.Key;
            if key ~= 'f' % character that closes connection; connection at this point will already be closed
                if app.arduinoOn
                    write(app.arduino1, 'X', 'char');
                    flush(app.arduino1);
                end
                app.CurrentStateEditField.Value = 'No key';
            end
        end

        % Value changed function: ArduinoOnOffCheckBox
        function arduinoState(app, event)
            % determines whether the arduino connection needs to be made
            value = app.ArduinoOnOffCheckBox.Value;
            if value
                app.arduinoOn = true;
                serialPort = app.COMPortEditField.Value; % must change depending on the port
                baudRate = 9600;
                app.arduino1 = serialport(serialPort, baudRate);
                configureTerminator(app.arduino1, "LF");
                flush(app.arduino1); % Clear the serial port buffer
                disp(serialPort)
                app.CurrentStateEditField.Value = 'Connected';
            else
                % closes connection if arduino checkbox is on
                if app.arduinoOn
                    write(app.arduino1, 'F', 'char');
                    flush(app.arduino1);
                    pause(1);
                    % Outputs the "target reached" response from the Arduino
                    data = readline(app.arduino1); 
                    app.CurrentStateEditField.Value = data;
                
                    % Clean up
                    clear app.arduino1;
                    app.arduinoOn = false;
                end
            end
        end

        % Value changed function: LiveVideoOnoffCheckBox
        function LiveVideoOn(app, event)
            % updates live video feed when box is checked
            value = app.LiveVideoOnoffCheckBox.Value;
            if value
                app.vid = webcam(2);
            end
            while (app.LiveVideoOnoffCheckBox.Value == 1)
                image = snapshot(app.vid);
                imshow(image, 'Parent', app.UIAxes)
                drawnow;
                pause(app.framerate);
            end
            clear app.vid;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.arduinoOn
                write(app.arduino1, 'F', "char");
                flush(app.arduino1);
                pause(1);
                clear app.arduino1;
                app.arduinoOn = false;
            end
            clear app.vid;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @directionsPressed, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @directionsReleased, true);
            app.LeftPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Video Feed')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ArduinoOnOffCheckBox = uicheckbox(app.LeftPanel);
            app.ArduinoOnOffCheckBox.ValueChangedFcn = createCallbackFcn(app, @arduinoState, true);
            app.ArduinoOnOffCheckBox.Text = 'Arduino On/Off';
            app.LiveVideoOnoffCheckBox = uicheckbox(app.LeftPanel);
            app.LiveVideoOnoffCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveVideoOn, true);
            app.LiveVideoOnoffCheckBox.Text = 'Live Video On/off';
            app.COMPortEditFieldLabel = uilabel(app.LeftPanel);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.LeftPanel, 'text');
            app.COMPortEditField.Value = 'COM6';
            app.CurrentStateEditFieldLabel = uilabel(app.LeftPanel);
            app.CurrentStateEditFieldLabel.Text = 'Current State';
            app.CurrentStateEditField = uieditfield(app.LeftPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end